classdef getpoints < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        PointCloudGenerationLabel  matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Window button down function: UIFigure
        function Alohomora(app, event)
            hold(app.UIAxes)
            pt = get(app.UIAxes, 'CurrentPoint');
            scatter(app.UIAxes, pt(1, 1), pt(1, 2), '.');
            app.UITable.Data = [app.UITable.Data; pt(1, 1) pt(1 ,2)];
            hold(app.UIAxes)
        end

        % Close request function: UIFigure
        function Closewindow(app, event)
            labels = {'Save x coordinates to a variable named:' ...
                      'Save y coordinates to a variable named:'}; 
            vars = {'x','y'}; 
            values = {app.UITable.Data(:, 1), app.UITable.Data(:, 2)};
            export2wsdlg(labels,vars,values);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Closewindow, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @Alohomora, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.UIAxes.XLim = [1 10];
            app.UIAxes.YLim = [1 10];
            app.PointCloudGenerationLabel = uilabel(app.UIFigure);
            app.PointCloudGenerationLabel.Text = 'Point Cloud Generation';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getpoints

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end